classdef LUC_webapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Slider                matlab.ui.control.Slider
        Label_11              matlab.ui.control.Label
        mode_choose           matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Unit_mode             matlab.ui.container.ButtonGroup
        Q                     matlab.ui.control.RadioButton
        T                     matlab.ui.control.RadioButton
        Tab_2                 matlab.ui.container.Tab
        solvea_mode           matlab.ui.container.ButtonGroup
        Button_6              matlab.ui.control.ToggleButton
        Button_5              matlab.ui.control.ToggleButton
        figure                matlab.ui.container.TabGroup
        result                matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        Tab_4                 matlab.ui.container.Tab
        check_window          matlab.ui.control.UIAxes
        guass                 matlab.ui.control.NumericEditField
        Label_10              matlab.ui.control.Label
        Textdisp              matlab.ui.control.TextArea
        Label_9               matlab.ui.control.Label
        zoomchange            matlab.ui.control.Button
        zoom                  matlab.ui.control.NumericEditField
        Label_8               matlab.ui.control.Label
        Button_4              matlab.ui.control.Button
        timeset               matlab.ui.control.NumericEditField
        Label_7               matlab.ui.control.Label
        TextArea_2            matlab.ui.control.TextArea
        Label_6               matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        Label_5               matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        Label_4               matlab.ui.control.Label
        tittle                matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        chooseFile_element_2  matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        chooseFile_element    matlab.ui.control.Button
        Button                matlab.ui.control.Button
    end


    properties (Access = private)
        % 显示文件名
        File_elementFile
        File_nodeFile
        SelectedFcn; % 函数句柄
        SelectedeleRWmode
        timerecord
        Cul_mode = 1 %计算模式选择，默认为直接法
        nodeData %程序内广播 nodedata
        eleData %程序内广播 eleData
        a %程序内广播 a
        mode_flag
        pausetime
    end

    methods (Access = private)


        function Triangle_three_node(app)
            tic;
            addpath(".\公共函数\");
            addpath(".\3角形3节点单元有限元计算\");

            app.eleData = RWelements(app.File_elementFile);
            app.nodeData = RWnodes_coordinates(app.File_nodeFile);

            % 使用 cellfun 将每个元素乘以 0.001
            % app.nodeData = cellfun(@(x) x * 0.001, app.nodeData, 'UniformOutput', false);

            % 查询边界条件
            [xmaxlist,xmaxelelist] = findmaxele(app.nodeData,app.eleData);
            [xminlist,~] = findminele(app.nodeData,app.eleData);

            % 主！体！
            K = solveK(app.nodeData,app.eleData);
            P = solveP(xmaxlist,app.nodeData,xmaxelelist,app.eleData);

            app.a = solvea(K,P,app.nodeData,xminlist,app.Cul_mode);
            [~,p_x,p_y] = find_nikepoint(app.nodeData,xmaxlist,app.a);
            app.Textdisp.Value = sprintf("变形后坐标x:%.4f y:%.4f",p_x,p_y) ;
            draw(app.nodeData,app.eleData,app.a,app.UIAxes,app.zoom.Value,app.mode_flag);
            rmpath(".\3角形3节点单元有限元计算\");
            rmpath(".\公共函数\");
            app.timerecord = toc;
        end


        function Quadrilateral_four_node(app)
            tic;
            addpath(".\4边形4节点单元有限元计算\");
            addpath(".\公共函数\");
            xn = app.guass.Value;
            app.eleData = RWelements(app.File_elementFile);
            app.nodeData = RWnodes_coordinates(app.File_nodeFile);
            [xmaxlist,xmaxelelist] = findmaxele(app.nodeData,app.eleData);
            [xminlist,~] = findminele(app.nodeData,app.eleData);
            K = solveK(app.nodeData,app.eleData,xn);
            P = solveP(xmaxlist,app.eleData,app.nodeData,xmaxelelist,xn);
            app.a = solvea(K,P,app.nodeData,xminlist,app.Cul_mode);
            [~,p_x,p_y] = find_nikepoint(app.nodeData,xmaxlist,app.a);

            app.Textdisp.Value = sprintf("变形后坐标x:%.4f y:%.4f",p_x,p_y) ;
            %绘图
            draw(app.nodeData,app.eleData,app.a,app.UIAxes,app.zoom.Value,app.mode_flag);
            rmpath(".\4边形4节点单元有限元计算\");
            rmpath(".\公共函数\");
            app.timerecord = toc;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPush(app, event)
            app.Button.Icon='./Icon/1.jpg';
            if isa(app.SelectedFcn, 'function_handle')
                % app.SelectedFcn 是一个函数句柄
                app.SelectedFcn();
                app.Button.Icon = './Icon/2.jpg';
            else
                % app.SelectedFcn 不是一个函数句柄
                uialert(app.UIFigure, '请选择一个有效的操作', '提示');
            end
            app.timeset.Value = app.timerecord;
        end

        % Button pushed function: Button_4
        function check(app, event)
            addpath(".\公共函数\");
            cla(app.check_window);
            app.nodeData = RWnodes_coordinates(app.File_nodeFile);

            if app.mode_flag == 4
                addpath(".\4边形4节点单元有限元计算\");
                app.eleData = RWelements(app.File_elementFile);
                for i = 1:size(app.eleData,1)
                    [~,globe_xy] = find_globe(i,app.eleData,app.nodeData);
                    x = globe_xy(:,1);
                    y = globe_xy(:,2);
                    x_spc = [x(1),x(app.mode_flag)];
                    y_spc = [y(1),y(app.mode_flag)];
                    % rowname = string(rowIdx);
                    hold(app.check_window,"on")
                    scatter(app.check_window,x,y,16,"red");

                    plot(app.check_window,x(1:2),y(1:2),'Color',[0.5 0.5 0.5]);
                    plot(app.check_window,x(2:3),y(2:3),'Color',[0.5 0.5 0.5]);
                    plot(app.check_window,x(3:4),y(3:4),'Color',[0.5 0.5 0.5]);
                    plot(app.check_window,x_spc,y_spc,'Color',[0.5 0.5 0.5]);
                    if app.pausetime < 0
                        pause off
                    else
                        pause(app.pausetime)
                    end
                    hold(app.check_window,'off')
                end
                rmpath(".\4边形4节点单元有限元计算\");
            elseif app.mode_flag == 3
                addpath(".\3角形3节点单元有限元计算\");
                app.eleData = RWelements(app.File_elementFile);
                for i = 1:size(app.eleData,1)
                    [~,globe_xy] = find_globe(i,app.eleData,app.nodeData);
                    x = globe_xy(:,1);
                    y = globe_xy(:,2);
                    x_spc = [x(1),x(app.mode_flag)];
                    y_spc = [y(1),y(app.mode_flag)];
                    % rowname = string(rowIdx);
                    hold(app.check_window,"on")
                    scatter(app.check_window,x,y,16,"red");
                    
                    plot(app.check_window,x(1:2),y(1:2),'Color',[0.5 0.5 0.5]);
                    plot(app.check_window,x(2:3),y(2:3),'Color',[0.5 0.5 0.5]);
                    plot(app.check_window,x_spc,y_spc,'Color',[0.5 0.5 0.5]);
                    if app.pausetime < 0
                        pause off
                    else
                        pause(app.pausetime)
                    end

                    hold(app.check_window,'off')
                end
                rmpath(".\3角形3节点单元有限元计算\");
            end
            rmpath(".\公共函数\");
        end

        % Button pushed function: zoomchange
        function zoomchangePushed(app, event)
            addpath(".\公共函数\");
            cla(app.UIAxes);
            draw(app.nodeData,app.eleData,app.a,app.UIAxes,app.zoom.Value,app.mode_flag);
            rmpath(".\公共函数\");
        end

        % Selection changed function: Unit_mode
        function Unit_modeSelectionChanged(app, event)

            % 根据选择的按钮设置app的内部状态
            switch app.Unit_mode.SelectedObject.Text
                case '三角形三节点'
                    app.SelectedFcn = @app.Triangle_three_node;
                    app.mode_flag = 3;
                    app.Lamp.Color = 'g';
                    app.Button.Icon = './Icon/2.jpg';
                case '四边形四节点'
                    app.SelectedFcn = @app.Quadrilateral_four_node;
                    app.mode_flag = 4;
                    app.Lamp.Color = 'b';
                    app.Button.Icon = './Icon/2.jpg';
            end
        end

        % Button pushed function: chooseFile_element
        function chooseFile_elementPushed(app, event)
            [file, path] = uigetfile({'*.txt*', '文本文档(*.txt)'}, '选择文件');
            % 检查用户是否选择了文件
            if isequal(file, 0) || isequal(path, 0)
                errordig  ("没有选择文件","错误");
            else
                % 获取完整的文件路径
                app.File_elementFile = strcat(path, file);

            end
            app.TextArea.Value = app.File_elementFile;
        end

        % Button pushed function: chooseFile_element_2
        function chooseFile_node(app, event)
            [file, path] = uigetfile({'*.txt*', '文本文档(*.txt)'}, '选择文件');
            % 检查用户是否选择了文件
            if isequal(file, 0) || isequal(path, 0)
                errordig  ("没有选择文件","错误");
            else
                % 获取完整的文件路径
                app.File_nodeFile = strcat(path, file);
            end
            app.TextArea_2.Value = app.File_nodeFile;
        end

        % Selection changed function: solvea_mode
        function solvea_modeSelectionChanged(app, event)
            selectedButton = app.solvea_mode.SelectedObject.Text;
            switch selectedButton
                case '直接求解法'
                    app.Cul_mode = 1;
                case '对角化一'
                    app.Cul_mode = 2;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.pausetime = app.Slider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '有限元计算';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPush, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Icon', '0.jpg');
            app.Button.Text = '';
            app.chooseFile_element = uibutton(app.UIFigure, 'push');
            app.chooseFile_element.ButtonPushedFcn = createCallbackFcn(app, @chooseFile_elementPushed, true);
            app.chooseFile_element.Text = '选择文件';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择单元节点文件';
            app.chooseFile_element_2 = uibutton(app.UIFigure, 'push');
            app.chooseFile_element_2.ButtonPushedFcn = createCallbackFcn(app, @chooseFile_node, true);
            app.chooseFile_element_2.Text = '选择文件';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选择节点坐标文件';
            app.tittle = uilabel(app.UIFigure);
            app.tittle.Text = '有限元计算';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '模式';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '单元节点文件';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '节点坐标文件';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '运行用时';
            app.timeset = uieditfield(app.UIFigure, 'numeric');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @check, true);
            app.Button_4.Text = '检查有限元';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '缩放倍数';
            app.zoom = uieditfield(app.UIFigure, 'numeric');
            app.zoom.Value = 1;
            app.zoomchange = uibutton(app.UIFigure, 'push');
            app.zoomchange.ButtonPushedFcn = createCallbackFcn(app, @zoomchangePushed, true);
            app.zoomchange.Text = '修改缩放';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '关键点';
            app.Textdisp = uitextarea(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '四节点运算时高斯点选取个数';
            app.guass = uieditfield(app.UIFigure, 'numeric');
            app.guass.Limits = [1 4];
            app.guass.Value = 2;
            app.figure = uitabgroup(app.UIFigure);
            app.result = uitab(app.figure);
            app.result.Title = '结果';
            app.UIAxes = uiaxes(app.result);
            title(app.UIAxes, '结果可视化控件')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab_4 = uitab(app.figure);
            app.Tab_4.Title = '有限元检查';
            app.check_window = uiaxes(app.Tab_4);
            title(app.check_window, '有限元生成与检查')
            xlabel(app.check_window, 'X')
            ylabel(app.check_window, 'Y')
            zlabel(app.check_window, 'Z')
            app.mode_choose = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.mode_choose);
            app.Tab.Title = '单元模式';
            app.Unit_mode = uibuttongroup(app.Tab);
            app.Unit_mode.SelectionChangedFcn = createCallbackFcn(app, @Unit_modeSelectionChanged, true);
            app.T = uiradiobutton(app.Unit_mode);
            app.T.Text = '三角形三节点';
            app.T.Value = true;
            app.Q = uiradiobutton(app.Unit_mode);
            app.Q.Text = '四边形四节点';
            app.Tab_2 = uitab(app.mode_choose);
            app.Tab_2.Title = '计算模式';
            app.solvea_mode = uibuttongroup(app.Tab_2);
            app.solvea_mode.SelectionChangedFcn = createCallbackFcn(app, @solvea_modeSelectionChanged, true);
            app.Button_5 = uitogglebutton(app.solvea_mode);
            app.Button_5.Text = '直接求解法';
            app.Button_5.Value = true;
            app.Button_6 = uitogglebutton(app.solvea_mode);
            app.Button_6.Text = '对角化一';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '速度';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-0.5 2];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUC_webapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end